classdef MuscleSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ModeButtonGroup          matlab.ui.container.ButtonGroup
        MotionSelectionButton    matlab.ui.control.RadioButton
        MuscleSelectionButton    matlab.ui.control.RadioButton
        MuscleSelectionDropDown  matlab.ui.control.DropDown
        MotionSelectionDropDown  matlab.ui.control.DropDown
        ActivateSwitchLabel      matlab.ui.control.Label
        ActivateSwitch           matlab.ui.control.Switch
    end


    properties (Access = public)
        EmgSource;
    end
    
    
    properties (Access = private)
       
        % Channel properties
        % NOTE: RIGHT NOW NEED TO BE VERY CAREFUL CHANGINE THESE AS THESE ARE HARDCODED TO CORRESPOND TO THE EMGPATTERN FILE AND ALSO THE PROTOCOL FOR SENDING OUT WHICH MUSCLES ARE ACTIVATES
       Muscles = {'Flexor Carpi Ulnaris', 'Palmaris Longus', 'Flexor Carpi Radialis', 'Pronator Teres', 'Brachioradialis', 'Extensor Carpi Radialis Longus', 'Extensor Carpi Radialis Brevis', 'Extensor Digitorum Communis', 'Extensor Digiti Minimi', 'Extensor Carpi Ulnaris', 'Biceps Brachii', 'Coracobrachialis', 'Brachialis', 'Triceps Brachii'}; % Description
       Motions = {'Elbow Flex', 'Elbow Extend','Wrist Flex In', 'Wrist Extend Out', 'Hand Open', 'Tip Grasp', 'Power Grasp', 'NoMotion'};
       % TODO: THESE COULD BE NON-BINARY TO INDICATE DEGREE TO WHICH THEY ARE ACTIVATING
       MuscleMotionMap = [0 0 0 0 0 0 0 0 0 0 1 1 1 0;
                          0 0 0 0 0 0 0 0 0 0 0 0 0 1;
                          1 1 1 1 0 0 0 0 0 0 0 0 0 0;
                          0 0 0 0 1 1 1 1 1 1 0 0 0 0;
                          0 0 0 0 0 0 0 1 1 0 0 0 0 0
                          0 0 1 0 0 0 0 1 1 0 0 0 0 0
                          0 0 1 0 1 0 0 0 0 0 0 0 0 0
                          0 0 0 0 0 0 0 0 0 0 0 0 0 0]; % NumMotions x NumMuscles
       
       % Pattern tracker
       EmgPatternFileName = 'emgPatternData2.mat';
       NoMotionPattern = 8;
       ActivePattern = 1;

       % Streaming properties
       Timer;
       isRunning = false;
       MuscleSink;
       %EmgSink;
       
    end
    
    properties (Dependent)
        NumChannelsToMap;
        NumMuscles;
        NumMotions;
    end
    
    methods
         % Get Methods
        function value = get.NumMuscles(app)
            value = length(app.Muscles);
        end
        function value = get.NumMotions(app)
            value = length(app.Motions);
        end
        
    end
    methods (Access = private)  

        function PutData(app)
%                 emg = typecast(int8(120*(app.EmgSource.getData(1)-1.2)), 'uint8');
%                 quat = typecast(single([1 0 0 0]),'uint8');
%                 acc = typecast(single([0 0 0]),'uint8');
%                 gyro = typecast(single([0 0 0]),'uint8');
%                 msg = cat(2,emg,quat,acc,gyro);
%                 app.Sink.putData(msg)
            
            % Send out muscle activation index
            selectedButton = app.ModeButtonGroup.SelectedObject;
            
            switch selectedButton
                case app.MotionSelectionButton
                    motionIdx = find(strcmp(app.MotionSelectionDropDown.Items, app.MotionSelectionDropDown.Value));
                    muscleIdx = app.MuscleMotionMap(motionIdx,:);
                case app.MuscleSelectionButton
                    muscleIdx = zeros(1,size(app.MuscleMotionMap,2));
                    selectedIdx = find(strcmp(app.MuscleSelectionDropDown.Items, app.MuscleSelectionDropDown.Value));
                    muscleIdx(selectedIdx) = 1;
            end
            
            msg = typecast(muscleIdx, 'uint8');
            app.MuscleSink.putData(msg);
            
            %muscleIdx
            
            
            %pause(1/200)
        end
        
        function StartUdpStream(app)
            

%             %% Stream simulator with intent of emulating Myo Arm Band
            
            % TODO: Make a dropdown menu for setting port or at least make a property
            
%             app.EmgSource = Inputs.SignalSimulator();
%             app.EmgSource.EnableGui = false;
%             app.EmgSource.initialize();
%             app.EmgSource.setPattern(0);
            

%             app.EmgSource = Inputs.EmgSimulator(app.EmgPatternFileName);
%             app.EmgSource.initialize();
%             app.EmgSource.setPattern(9); % No movement class
            
            
%             UdpLocalPort = 56789;
%             UdpDestinationPort = 10001;
%             UdpAddress = '127.0.0.1'; % '192.168.1.101';
%             % PnetClass(localPort,remotePort,remoteIP)
%             app.EmgSink = PnetClass(UdpLocalPort,UdpDestinationPort,UdpAddress);
%             app.EmgSink.initialize()
% 

            UdpLocalPort = 56789;
            UdpDestinationPort = 10009;
            UdpAddress = '127.0.0.1'; % '192.168.1.101';
            % PnetClass(localPort,remotePort,remoteIP)
            app.MuscleSink = PnetClass(UdpLocalPort,UdpDestinationPort,UdpAddress);
            app.MuscleSink.initialize()

        end
 
    end
        

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize dropdown menus
            app.MuscleSelectionDropDown.Items = app.Muscles;
            app.MotionSelectionDropDown.Items = app.Motions;
            if length (app.MuscleSelectionDropDown.Items) > 15
               warning('More than 15 muscles supplied and only 15 unique signals available');
            end
            if length (app.MotionSelectionDropDown.Items) > 15
               warning('More than 15 motions supplied and only 15 unique signals available');
            end
            
            app.EmgSource = Inputs.EmgSimulator(app.EmgPatternFileName, 0);
            app.EmgSource.initialize();
            app.EmgSource.start();
            app.EmgSource.setPattern(app.NoMotionPattern); % No movement class
            
            % Start Udp stream, it will always be streaming while this GUI is on
            app.StartUdpStream();
            app.Timer = UiTools.create_timer(mfilename,@(src,evt)app.PutData);
            app.Timer.Period = 0.1;
            start(app.Timer);
            
        end

        % Selection changed function: ModeButtonGroup
        function ModeChanged(app, event)
            selectedButton = app.ModeButtonGroup.SelectedObject;
            
            switch selectedButton
                case app.MotionSelectionButton
                    app.MuscleSelectionDropDown.Enable = 0;
                    app.MotionSelectionDropDown.Enable = 1;
                    app.ActivateSwitch.Value = 'Off';
                case app.MuscleSelectionButton
                    app.MuscleSelectionDropDown.Enable = 1;
                    app.MotionSelectionDropDown.Enable = 0;
                    app.ActivateSwitch.Value = 'Off';
            end
            
        end

        % Value changed function: MotionSelectionDropDown
        function MotionChanged(app, event)
            value = app.MotionSelectionDropDown.Value;
            idx = find(strcmp(app.MotionSelectionDropDown.Items, value));
            app.ActivePattern = idx;
            
            value = app.ActivateSwitch.Value;
            switch value
                case 'Off'
                    app.EmgSource.setPattern(app.NoMotionPattern);
                case 'On'
                    app.EmgSource.setPattern(app.ActivePattern);
            end
            
            
        end

        % Value changed function: MuscleSelectionDropDown
        function MuscleChanged(app, event)
            value = app.MuscleSelectionDropDown.Value;
            idx = find(strcmp(app.MuscleSelectionDropDown.Items, value));
            app.ActivePattern = idx;
            
            value = app.ActivateSwitch.Value;
            switch value
                case 'Off'
                    app.EmgSource.setPattern(app.NoMotionPattern);
                case 'On'
                    app.EmgSource.setPattern(app.ActivePattern);
            end
        end

        % Value changed function: ActivateSwitch
        function ActivationChanged(app, event)
            value = app.ActivateSwitch.Value;
            
            switch value
                case 'Off'
                    app.EmgSource.setPattern(app.NoMotionPattern);
                case 'On'
                    app.EmgSource.setPattern(app.ActivePattern);
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %stop(app.Timer);
            app.EmgSource.stop();
            app.MuscleSink.close();
            %app.EmgSink.close();
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.MotionSelectionButton = uiradiobutton(app.ModeButtonGroup);
            app.MotionSelectionButton.Text = 'Motion Selection';
            app.MotionSelectionButton.Value = true;
            app.MuscleSelectionButton = uiradiobutton(app.ModeButtonGroup);
            app.MuscleSelectionButton.Text = 'Muscle Selection';
            app.MuscleSelectionDropDown = uidropdown(app.ModeButtonGroup);
            app.MuscleSelectionDropDown.Items = {'NA'};
            app.MuscleSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MuscleChanged, true);
            app.MuscleSelectionDropDown.Enable = 'off';
            app.MuscleSelectionDropDown.Value = 'NA';
            app.MotionSelectionDropDown = uidropdown(app.ModeButtonGroup);
            app.MotionSelectionDropDown.Items = {'NA'};
            app.MotionSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MotionChanged, true);
            app.MotionSelectionDropDown.Value = 'NA';
            app.ActivateSwitchLabel = uilabel(app.UIFigure);
            app.ActivateSwitchLabel.Text = {'Activate'; ''};
            app.ActivateSwitch = uiswitch(app.UIFigure, 'slider');
            app.ActivateSwitch.ValueChangedFcn = createCallbackFcn(app, @ActivationChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = MuscleSimulator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end